classdef Full_GUI_Part1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ImageDetailsPanel         matlab.ui.container.Panel
        NameLabel                 matlab.ui.control.Label
        NameField                 matlab.ui.control.EditField
        FormatLabel               matlab.ui.control.Label
        FormatField               matlab.ui.control.EditField
        SizeLabel                 matlab.ui.control.Label
        SizeField                 matlab.ui.control.EditField
        LoadPatternButton         matlab.ui.control.Button
        CrossCorrelationButton    matlab.ui.control.Button
        HoughLineTransformButton  matlab.ui.control.Button
        GradientButton            matlab.ui.control.Button
        EdgeDetectionButton       matlab.ui.control.Button
        ThresholdButton           matlab.ui.control.Button
        HistogramButton           matlab.ui.control.Button
        MedianFilterButton        matlab.ui.control.Button
        ExitButton                matlab.ui.control.Button
        LoadImageButton           matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    properties (Access = private)
        OriginalImage % Store the original image
        GrayscaleImage % Store the grayscale
        NoisyImage % Store the noisy image
        FilteredImage % Store the median filtered image
        PatternImage % Store pattern image
        CurrentFilname % Store current file name
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            try
                [file, path] = uigetfile({'*.jpg;*.png;*.bmp;*.tiff', ...
                    'Image Files (*.jpg, *.png, *.bmp, *.tiff)'});

                if isequal(file, 0)
                    disp("User Selected Cancel...");
                    return;
                end

                % store the full file path
                fullPath = fullfile(path, file);
                app.CurrentFilname = file;

                % Read & store the original image
                app.OriginalImage = imread(fullPath);

                % Display image in UIAxes
                imshow(app.OriginalImage, "Parent", app.UIAxes);
                title(app.UIAxes, file);

                % get img info
                info = imfinfo(fullPath);

                % update img details
                [~, name, ~] = fileparts(file);
                app.NameField.Value = name;
                app.FormatField.Value = info.Format;
                app.SizeField.Value = sprintf('%d x %d pixels', info.Width, info.Height);

                % clear the filtered image
                app.FilteredImage = [];

                % Display status of processing
                app.UIAxes.Title.String = sprintf("Loaded:%S", file);

                % Display original image
                figure;
                imshow(app.OriginalImage);
                title('Original Image');

                % Convert to grayscale
                grayImage = rgb2gray(app.OriginalImage);

                % Display grayscale image
                figure;
                imshow(grayImage);
                title('Grayscale Image');

            catch ME
                % handle errors
                errordlg(["ERROR in loading image!", ME.message], "Error");
                app.UIAxes.Title.String = "Error in loading image!";
            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            % Close the app
            delete(app.UIFigure);
        end

        % Button pushed function: MedianFilterButton
        function MedianFilterButtonPushed(app, event)
            if ~isempty(app.OriginalImage)
                % Convert the original image to grayscale if it is RGB
                if size(app.OriginalImage, 3) == 3
                    app.GrayscaleImage = rgb2gray(app.OriginalImage);
                else
                    app.GrayscaleImage = app.OriginalImage; % already grayscale
                end

                % Display the original image
                figure("Name", "Original Image");
                imshow(app.OriginalImage);
                title("Original Image");

                % Display the grayscale image
                figure("Name", "Grayscale Image");
                imshow(app.GrayscaleImage);
                title("Grayscale Image");

                % Generate Gaussian noise and add it to the grayscale image
                % Gaussian white noise w/ mean =0  & variance = 0.01.
                noisyImage = imnoise(app.GrayscaleImage, 'gaussian', 0, 0.01); 

                % Display the noisy grayscale image
                figure("Name", "Noisy Grayscale Image");
                imshow(noisyImage);
                title("Noisy Grayscale Image");

                % Apply median filter to the noisy image
                app.FilteredImage = medfilt2(noisyImage);

                % Display the median filtered image
                figure("Name", "Median Filtered Image");
                imshow(app.FilteredImage);
                title("Median Filtered Image");

            else
                uialert(app.UIFigure, "Please load an image first!", "Error");
            end
        end

        % Button pushed function: HistogramButton
        function HistogramButtonPushed(app, event)
            % checks if image is loaded
            if isempty(app.OriginalImage)
                uialert(app.UIFigure, "Please load an image first...")
                return;
            end

            % Perform histogram equalization on original img
            if size(app.OriginalImage, 3) == 3
                grayOriginal = rgb2gray(app.OriginalImage);
                equalizedOriginal = histeq(grayOriginal);
            else
                grayOriginal = app.OriginalImage;
                equalizedOriginal = histeq(grayOriginal);
            end

            % Display the original image & equalized images
            figure("Name", "Original Image Histogram Equalization");
            subplot(1, 2, 1);
            imshow(grayOriginal);
            title("Original Grayscale Image")

            subplot(1,2,2);
            imshow(equalizedOriginal);
            title("Equalized Image");

            figure("Name", "Histograms");

            % Display Histogram of original grayscale image
            subplot(1, 2, 1);
            imhist(grayOriginal);
            title("Histogram of Original Image");
            xlabel("Pixel Intensity");
            ylabel("Frequency");


            % Display the Histogram of equalized image
            subplot(1, 2, 2);
            imhist(equalizedOriginal);
            title("Histogram of Equalized Image");
            xlabel("Pixel Intensity");
            ylabel("Frequency");
        end

        % Button pushed function: ThresholdButton
        function ThresholdButtonPushed(app, event)
            if isempty(app.OriginalImage)
                uialert(app.UIFigure, "Please load an image first!", "Error");
                return;
            end

            % conversion to grayscale if RGB image
            if size(app.OriginalImage, 3) == 3
                grayImage = rgb2gray(app.OriginalImage);
            else
                grayImage = app.OriginalImage; % Already grayscale
            end

            % Apply 2 thresholds
            t1 = 50;
            t2 = 200;
            binaryImage1 = grayImage > t1;
            binaryImage2 = grayImage > t2;


            % Display the image in separate figures
            figure("Name", "Thresholding");
            subplot(1,3,1);
            imshow(grayImage);
            title("Original Image");

            % Image when T = 50
            subplot(1,3,2);
            imshow(binaryImage1);
            title("Threshold Image, T = 50");

            % Image when T = 200
            subplot(1,3,3);
            imshow(binaryImage2);
            title("Threshold Image, T = 200");      
        end

        % Button pushed function: EdgeDetectionButton
        function EdgeDetectionButtonPushed(app, event)
            if isempty(app.OriginalImage)
                uialert(app.UIFigure, "Please load an image first!", "Error");
                return;
            end

            % conversion to grayscale if RGB image
            if size(app.OriginalImage, 3) == 3
                grayImage = rgb2gray(app.OriginalImage);
            else
                grayImage = app.OriginalImage; % Already grayscale
            end
            
            % Use Sobel 
            sobelEdges = edge(grayImage, "sobel");

            % Use Canny
            cannyEdges = edge(grayImage, "canny");

            % Display edge detection methods side by side
            figure("Name", "Edge Detection Results");
            subplot(1,3,1);
            imshow(grayImage);
            title("Original Image");

            subplot(1,3,2);
            imshow(sobelEdges);
            title("Sobel Edge Detection");

            subplot(1,3,3);
            imshow(cannyEdges);
            title("Canny Edge Detection");    
        end

        % Button pushed function: GradientButton
        function GradientButtonPushed(app, event)
            if isempty(app.OriginalImage)
                uialert(app.UIFigure, 'Please load an image first!', 'Error');
                return;
            end

            % Convert to grayscale if the image is RGB
            if size(app.OriginalImage, 3) == 3
                grayImage = rgb2gray(app.OriginalImage);
            else
                grayImage = app.OriginalImage; % grayscale
            end

            % Generate Gaussian noise image
            noiseImage = imnoise(grayImage, 'gaussian', 0, 0.01); 

            % Compute gradients using Sobel and Prewitt operators
            [GxS, GyS] = imgradientxy(noiseImage, 'sobel');
            [GxP, GyP] = imgradientxy(noiseImage, 'prewitt');

            % Compute gradients using Roberts operator
            robertsX = [1 0; 0 -1];
            robertsY = [0 1; -1 0];
            GxR = imfilter(noiseImage, robertsX, 'replicate');
            GyR = imfilter(noiseImage, robertsY, 'replicate');

            % Compute the magnitude of the gradients
            magS = sqrt(double(GxS).^2 + double(GyS).^2);
            magP = sqrt(double(GxP).^2 + double(GyP).^2);
            magR = sqrt(double(GxR).^2 + double(GyR).^2);

            % Smooth the gradient magnitudes using Gaussian filter
            smoothMagS = imgaussfilt(magS, 2); 
            smoothMagP = imgaussfilt(magP, 2);
            smoothMagR = imgaussfilt(magR, 2);

            % Displaying the Gaussian noise image
            figure('Name', 'Gaussian Noise Image');
            imshow(noiseImage, []);
            title('Gaussian Noise Image');

            % Display Sobel gradient & its smoothed version
            figure('Name', 'Sobel Gradient');
            subplot(1, 2, 1);
            imshow(magS, []);
            title('Sobel Gradient');

            subplot(1, 2, 2);
            imshow(smoothMagS, []);
            title('Smoothed Sobel Gradient');

            % Display Prewitt gradient & its smoothed version
            figure('Name', 'Prewitt Gradient');
            subplot(1, 2, 1);
            imshow(magP, []);
            title('Prewitt Gradient');

            subplot(1, 2, 2);
            imshow(smoothMagP, []);
            title('Smoothed Prewitt Gradient');

            % Display Roberts gradient & its smoothed version
            figure('Name', 'Roberts Gradient');
            subplot(1, 2, 1);
            imshow(magR, []);
            title('Roberts Gradient');

            subplot(1, 2, 2);
            imshow(smoothMagR, []);
            title('Smoothed Roberts Gradient');
        end

        % Button pushed function: HoughLineTransformButton
        function HoughLineTransformButtonPushed(app, event)
            if isempty(app.OriginalImage)
                uialert(app.UIFigure, 'Please load an image first!', 'Error');
                return;
            end

            % Convert to grayscale if the image is RGB
            if size(app.OriginalImage, 3) == 3
                grayImage = rgb2gray(app.OriginalImage);
            else
                grayImage = app.OriginalImage; % Already grayscale
            end

            % Rotate the grayscale image by 33
            rotatedImage = imrotate(grayImage, 33);

            % Edge detection using Canny method
            edges = edge(rotatedImage, 'Canny');

            % Perform Hough Transform
            [H, theta, rho] = hough(edges);

            % Find peaks in the Hough transform
            peaks = houghpeaks(H, 5, 'threshold', ceil(0.3 * max(H(:))));

            % Extract lines based on the peaks from the rotated image
            lines = houghlines(edges, theta, rho, peaks, 'FillGap', 5, 'MinLength', 7);

            % Create a new figure for displaying the rotated grayscale image
            figure('Name', 'Rotated Grayscale Image');
            imshow(rotatedImage);
            title('Rotated Grayscale Image');

            % Create a new figure for displaying the edge-detected image
            figure('Name', 'Edge Detection');
            imshow(edges);
            title('Edge Detection (Canny)');

            % Create a new figure for displaying the Hough Transform
            figure('Name', 'Hough Transform');
            imagesc(theta, rho, H);
            colormap('hot');
            axis on;
            title('Hough Transform');
            xlabel('Theta (degrees)');
            ylabel('Rho (pixels)');
            hold on;

            % Plot the Hough peaks
            plot(peaks(:, 2), peaks(:, 1), 's', 'color', 'blue', 'MarkerSize', 10);
            hold off;

            % Create a new figure for displaying the detected lines on the rotated image
            figure('Name', 'Detected Lines on Rotated Image');
            imshow(rotatedImage); % Display the rotated grayscale image
            hold on;

            % Plot the detected lines on the rotated image
            for k = 1:length(lines)
                xy = [lines(k).point1; lines(k).point2];
                plot(xy(:,1), xy(:,2), 'LineWidth', 2, 'Color', 'red');
            end
            title('Detected Lines on Rotated Image');
            hold off;
        end

        % Button pushed function: CrossCorrelationButton
        function CrossCorrelationButtonPushed(app, event)
            % Check if the original image is loaded
            if isempty(app.OriginalImage)
                uialert(app.UIFigure, 'Please load an original image first!', 'Error');
                return;
            end
            
            % Check if the pattern image is loaded
            if isempty(app.PatternImage)
                uialert(app.UIFigure, 'Please load a pattern image first!', 'Error');
                return;
            end
            
            % Convert the original image to grayscale if it is RGB
            if size(app.OriginalImage, 3) == 3
                originalGray = rgb2gray(app.OriginalImage);
            else
                originalGray = app.OriginalImage; % Already grayscale
            end

            % Convert the pattern image to grayscale if it is RGB
            if size(app.PatternImage, 3) == 3
                patternGray = rgb2gray(app.PatternImage);
            else
                patternGray = app.PatternImage; % Already grayscale
            end

            % Perform normalized cross-correlation
            correlationOutput = normxcorr2(patternGray, originalGray);

            % Find the peak in the correlation output
            [ypeak, xpeak] = find(correlationOutput == max(correlationOutput(:)));
            
            % Calculate the (x,y) coordinates of the rectangle to be drawn
            corr_offset = [(xpeak - size(patternGray, 2)), (ypeak - size(patternGray, 1))];

            % Create a new figure for displaying the original image
            figure('Name', 'Original Image');
            imshow(app.OriginalImage);
            title('Original Image');

            % Create a new figure for displaying the cross-correlation result
            figure('Name', 'Cross-Correlation Result');
            imshow(correlationOutput, []);
            title('Cross-Correlation Result');
            colormap('jet'); % visualize the cross-correlation
            colorbar; % colorbar to indicate correlation values

            % Overlaying the detected pattern on the original image
            figure('Name', 'Detected Pattern');
            imshow(app.OriginalImage);
            hold on;
            rectangle('Position', [corr_offset(1), corr_offset(2), size(patternGray, 2), size(patternGray, 1)], ...
                      'EdgeColor', 'r', 'LineWidth', 2);
            title('Detected Pattern');
            hold off;
        end

        % Button pushed function: LoadPatternButton
        function LoadPatternButtonPushed(app, event)
            % Open a file selection dialog to choose an image file
            [file, path] = uigetfile({'*.png;*.jpg;*.jpeg;*.bmp', 'Image Files (*.png, *.jpg, *.jpeg, *.bmp)'});

            % Check if the user canceled the file selection
            if isequal(file, 0)
                return; % User canceled the file selection
            end

            % Construct the full file path
            fullFilePath = fullfile(path, file);

            % Load the image and store it in the app
            app.PatternImage = imread(fullFilePath);

            % Create a new figure window to display the image
            imgFigure = figure('Name', 'Pattern Image', 'NumberTitle', 'off');

            % Display the image in the new figure
            imshow(app.PatternImage, 'InitialMagnification', 'fit');

            % Optionally, set the title of the figure
            title('Loaded Pattern Image');

            % Show success alert
            uialert(app.UIFigure, 'Pattern image loaded successfully.', 'Success!');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.MedianFilterButton = uibutton(app.UIFigure, 'push');
            app.MedianFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MedianFilterButtonPushed, true);
            app.MedianFilterButton.Text = 'Median Filter';
            app.HistogramButton = uibutton(app.UIFigure, 'push');
            app.HistogramButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonPushed, true);
            app.HistogramButton.Text = 'Histogram';
            app.ThresholdButton = uibutton(app.UIFigure, 'push');
            app.ThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @ThresholdButtonPushed, true);
            app.ThresholdButton.Text = 'Threshold (T = 50 & T = 200)';
            app.EdgeDetectionButton = uibutton(app.UIFigure, 'push');
            app.EdgeDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @EdgeDetectionButtonPushed, true);
            app.EdgeDetectionButton.Text = 'Edge Detection';
            app.GradientButton = uibutton(app.UIFigure, 'push');
            app.GradientButton.ButtonPushedFcn = createCallbackFcn(app, @GradientButtonPushed, true);
            app.GradientButton.Text = 'Gradient';
            app.HoughLineTransformButton = uibutton(app.UIFigure, 'push');
            app.HoughLineTransformButton.ButtonPushedFcn = createCallbackFcn(app, @HoughLineTransformButtonPushed, true);
            app.HoughLineTransformButton.Text = 'Hough Line Transform';
            app.CrossCorrelationButton = uibutton(app.UIFigure, 'push');
            app.CrossCorrelationButton.ButtonPushedFcn = createCallbackFcn(app, @CrossCorrelationButtonPushed, true);
            app.CrossCorrelationButton.Text = 'Cross Correlation';
            app.LoadPatternButton = uibutton(app.UIFigure, 'push');
            app.LoadPatternButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPatternButtonPushed, true);
            app.LoadPatternButton.Text = 'Pattern Detection';
            app.ImageDetailsPanel = uipanel(app.UIFigure);
            app.ImageDetailsPanel.Title = 'Image Details';
            app.SizeField = uieditfield(app.ImageDetailsPanel, 'text');
            app.SizeLabel = uilabel(app.ImageDetailsPanel);
            app.SizeLabel.Text = 'Image Size:';
            app.FormatField = uieditfield(app.ImageDetailsPanel, 'text');
            app.FormatLabel = uilabel(app.ImageDetailsPanel);
            app.FormatLabel.Text = 'Image Format:';
            app.NameField = uieditfield(app.ImageDetailsPanel, 'text');
            app.NameLabel = uilabel(app.ImageDetailsPanel);
            app.NameLabel.Text = 'Image Name:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Full_GUI_Part1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end