classdef circle_Detection_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        MessageLabel         matlab.ui.control.Label
        circleDetect         matlab.ui.control.Label
        DetectCirclesButton  matlab.ui.control.Button
        LoadImageButton      matlab.ui.control.Button
        AxesGray             matlab.ui.control.UIAxes
        OriginalAxes         matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    properties (Access = private)
        rgbImage % rgb image
        grayImage % grayscale image
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.png;*.jpg;*.jpeg;*.bmp', 'Image Files (*.png, *.jpg, *.jpeg, *.bmp)'});
            if isequal(file, 0)
                return; % User canceled the file selection
            end
            app.rgbImage = imread(fullfile(path, file));
            imshow(app.rgbImage, 'Parent', app.OriginalAxes);
            title(app.OriginalAxes, 'Original Image');
            app.MessageLabel.Text = 'Image Loaded. Click "Detect Circles" to proceed.';
        end

        % Button pushed function: DetectCirclesButton
        function DetectCirclesButtonPushed(app, event)
            if isempty(app.rgbImage)
                app.MessageLabel.Text = 'Please load an image first.';
                return;
            end

            % Check if the image is grayscale or RGB
            if size(app.rgbImage, 3) == 3
                app.grayImage = im2gray(app.rgbImage);
            else
                app.grayImage = app.rgbImage; % Already grayscale
            end

            imshow(app.grayImage, 'Parent', app.AxesGray);
            title(app.AxesGray, 'Grayscale Image');
            
            % Detect dark circles
            [darkCenters, darkRadii] = imfindcircles(app.grayImage, [20 25], 'ObjectPolarity', 'dark', ...
                Sensitivity = 0.93, EdgeThreshold = 0.1);

            % Detect light circles
            [lightCenters, lightRadii] = imfindcircles(app.grayImage, [20 25], 'ObjectPolarity', 'bright', ...
                Sensitivity = 0.93, EdgeThreshold = 0.1);

            %centers = [darkCenters; lightCenters];
            %radii = [darkRadii; lightRadii];

            % Display results with detected circles
            cla(app.OriginalAxes); % Clear the original axes
            imshow(app.rgbImage, 'Parent', app.OriginalAxes);
            hold(app.OriginalAxes, 'on');

            % Plot dark circles in blue
            if ~isempty(darkCenters)
                viscircles(darkCenters, darkRadii, 'EdgeColor', 'b');
            end

            % Plot light circles in red
            if ~isempty(lightCenters)
                viscircles(lightCenters, lightRadii, 'EdgeColor', 'r');
            end

            title(app.OriginalAxes, 'Detected Circles');
            hold(app. OriginalAxes, 'off');

            % Show the number of detected circles in a pop-up dialog
            numDarkCircles = length(darkRadii);
            numLightCircles = length(lightRadii);
            totalCircles = numDarkCircles + numLightCircles;

            message = sprintf('Number of dark circles detected: %d\nNumber of light circles detected: %d\nTotal circles detected: %d', ...
                numDarkCircles, numLightCircles, totalCircles);
            msgbox(message, 'Detection Result');

            % Optional: Print details of detected circles in the command window
            for i = 1:numDarkCircles
                fprintf('Dark Circle %d: Center = (%.2f, %.2f), Radius = %.2f\n', i, darkCenters(i,1), darkCenters(i,2), darkRadii(i));
            end

            for i = 1:numLightCircles
                fprintf('Light Circle %d: Center = (%.2f, %.2f), Radius = %.2f\n', i, lightCenters(i,1), lightCenters(i,2), lightRadii(i));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OriginalAxes = uiaxes(app.UIFigure);
            title(app.OriginalAxes, 'Original Image')
            app.OriginalAxes.XTick = [];
            app.OriginalAxes.XTickLabel = '';
            app.OriginalAxes.YTick = [];
            app.OriginalAxes.YTickLabel = '';
            app.AxesGray = uiaxes(app.UIFigure);
            title(app.AxesGray, 'Grayscale Image')
            app.AxesGray.XTick = [];
            app.AxesGray.YTick = [];
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.DetectCirclesButton = uibutton(app.UIFigure, 'push');
            app.DetectCirclesButton.ButtonPushedFcn = createCallbackFcn(app, @DetectCirclesButtonPushed, true);
            app.DetectCirclesButton.Text = 'Detect Circles';
            app.circleDetect = uilabel(app.UIFigure);
            app.circleDetect.Text = 'Circle Detection using Hough Transform';
            app.MessageLabel = uilabel(app.UIFigure);
            app.MessageLabel.Text = 'Load an image to get started....';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = circle_Detection_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end